classdef ImportDataFeatureFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        ExcelPanel                 matlab.ui.container.Panel
        ChooseExcelButton          matlab.ui.control.Button
        ExcelFileLabel             matlab.ui.control.Label
        DataTableDropDown          matlab.ui.control.DropDown
        FeatureTableDropDown       matlab.ui.control.DropDown
        FeatureTableDropDownLabel  matlab.ui.control.Label
        DataTableDropDownLabel     matlab.ui.control.Label
        CSVPanel                   matlab.ui.container.Panel
        ChooseDataCSVButton        matlab.ui.control.Button
        CSVdataLabel               matlab.ui.control.Label
        ChoosePeakCSVButton        matlab.ui.control.Button
        CSVpeakLabel               matlab.ui.control.Label
        ImportButton               matlab.ui.control.Button
        ORLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        TidyConvertApp
        isXLS
        xlFullPathfilename
        ProjectName
        DataName
        PeakName
        DataTable 
        PeakTable
        csvFullPathDatafilename
        csvFullPathPeakfilename
        CurrentFolder
        Filename
    end
    
    properties (Access = public)
        
    end
    
    methods (Access = private)


        function reset(app)
            app.ExcelFileLabel.Text = '';
            app.DataTableDropDown.Items = {'-'};
            app.FeatureTableDropDown.Items = {'-'};
            app.CSVdataLabel.Text = '';
            app.CSVpeakLabel.Text = '';
            app.Filename = '';
            app.CurrentFolder = '';
            app.xlFullPathfilename = '';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Name = 'Import Data & Peak Tables';
            app.CallingApp = mainapp;
            if ~isempty(app.CallingApp.CurrentFolder)
                cd(app.CallingApp.CurrentFolder);
            end
        end

        % Button pushed function: ChooseExcelButton
        function ChooseExcelButtonPushed(app, event)
            app.isXLS = true;
            app.ExcelFileLabel.Text = '';
            app.DataTableDropDown.Items = {'-'};
            app.FeatureTableDropDown.Items = {'-'};
            app.CSVdataLabel.Text = '';
            app.CSVpeakLabel.Text = '';
            app.UIFigure.Visible = 'off';
            [file, path] = uigetfile('*.xlsx');
            figure(app.CallingApp.UIFigure);
            app.UIFigure.Visible = 'on';
           
            figure(app.UIFigure);
            %app.CallingApp.UIFigure.Visible = 'off';
            %app.CallingApp.UIFigure.Visible = 'on';
                       
            if isequal(file,0)
                 %uialert(app.UIFigure,'Failed to import data','Error','Icon','error');
                 return
            end
            app.Filename = file;
            app.CurrentFolder = path;
            cd(path);
            app.xlFullPathfilename = fullfile(path,file);
            xlSheetNames = sheetnames(app.xlFullPathfilename);
            app.ExcelFileLabel.Text = app.Filename;
            app.DataTableDropDown.Items = ['-';xlSheetNames];
            app.FeatureTableDropDown.Items = ['-';xlSheetNames];
        end

        % Button pushed function: ChooseDataCSVButton
        function ChooseDataCSVButtonPushed(app, event)
            app.ExcelFileLabel.Text = '';
            app.DataTableDropDown.Items = {''};
            app.FeatureTableDropDown.Items = {''};
            app.isXLS = false;

            app.UIFigure.Visible = 'off';
            [file, path] = uigetfile('*.csv');
            
            figure(app.CallingApp.UIFigure);
            app.UIFigure.Visible = 'on';
            figure(app.UIFigure);
            
                        
            if isequal(file,0)
                 uialert(app.UIFigure,'Failed to import csv file','Error','Icon','error');
                 return
            end
            app.Filename = file;
            app.CurrentFolder = path;
            cd(path);

            app.CSVdataLabel.Text = file;
            app.csvFullPathDatafilename = fullfile(path,file);         
            opts = detectImportOptions(app.csvFullPathDatafilename);
            if ~all(ismember({'SampleType','Batch','Order'},opts.VariableNames))
                pause(0.2);
                uialert(app.UIFigure,'Selection is not a DataTable','Warning','Icon','warning');
                app.CSVdataLabel.Text = '';
            end
                     
        end

        % Button pushed function: ChoosePeakCSVButton
        function ChoosePeakCSVButtonPushed(app, event)
            app.ExcelFileLabel.Text = '';
            app.DataTableDropDown.Items = {''};
            app.FeatureTableDropDown.Items = {''};
            app.isXLS = false;
            app.UIFigure.Visible = 'off';
            [file, path] = uigetfile('*.csv'); 
            figure(app.CallingApp.UIFigure);
            app.UIFigure.Visible = 'on';  
            figure(app.UIFigure);
            cd(path);

            % app.UIFigure.Visible = 'off';
            % app.UIFigure.Visible = 'on';            
            if isequal(file,0)
                 uialert(app.UIFigure,'Failed to import csv file','Error','Icon','error');
                 return
            end
            app.CSVpeakLabel.Text = file;
            app.csvFullPathPeakfilename = fullfile(path,file);
            opts = detectImportOptions(app.csvFullPathPeakfilename);
            if ~all(ismember({'UID','Name'},opts.VariableNames))
                pause(0.2);
                uialert(app.UIFigure,'Selection is not a PeakTable','Warning','Icon','warning');
                app.CSVpeakLabel.Text = '';
            end                 
        end

        % Value changed function: DataTableDropDown
        function DataTableDropDownValueChanged(app, event)
%             set(app.UIFigure, 'Pointer','watch')
%             app.DataTableDropDown.Enable = 'off';
%             app.FeatureTableDropDown.Enable = 'off';
%             app.ChooseExcelButton.Enable = 'off';
%             app.ImportButton.Enable = 'off';
%            drawnow();
            app.DataName = app.DataTableDropDown.Value;
%             opts = detectImportOptions(app.xlFullPathfilename,'Sheet',app.DataName);
%             if ~all(ismember({'SampleType','Batch','Order'},opts.VariableNames))
%                 pause(0.2);
%                 uialert(app.UIFigure,'Selection is not a DataTable','Warning','Icon','warning');
%                 app.DataTableDropDown.Value = '-';
%             end
%             set(app.UIFigure, 'Pointer','arrow')
%             app.DataTableDropDown.Enable = 'on';
%             app.FeatureTableDropDown.Enable = 'on';
%             app.ChooseExcelButton.Enable = 'on';
%             app.ImportButton.Enable = 'on';
        end

        % Value changed function: FeatureTableDropDown
        function FeatureTableDropDownValueChanged(app, event)
%             set(app.UIFigure, 'Pointer','watch')
%             app.DataTableDropDown.Enable = 'off';
%             app.FeatureTableDropDown.Enable = 'off';
%             app.ChooseExcelButton.Enable = 'off';
%             app.ImportButton.Enable = 'off';
%             drawnow();
            app.PeakName = app.FeatureTableDropDown.Value;
%             opts = detectImportOptions(app.xlFullPathfilename,'Sheet',app.PeakName);
%             if ~all(ismember({'UID','Name'},opts.VariableNames))
%                 pause(0.2);
%                 uialert(app.UIFigure,'Selection is not a PeakTable','Warning','Icon','warning');
%                 app.FeatureTableDropDown.Value = '-';
%             end
%             set(app.UIFigure, 'Pointer','arrow')
%             app.DataTableDropDown.Enable = 'on';
%             app.FeatureTableDropDown.Enable = 'on';
%             app.ChooseExcelButton.Enable = 'on';
%             app.ImportButton.Enable = 'on';
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
                if ismember('-',app.DataTableDropDown.Value)
                    uialert(app.UIFigure,'You need to select a Data Sheet','Failed to import data','Icon','error');
                    return;
                end
                if ismember('-',app.FeatureTableDropDown.Value)
                    uialert(app.UIFigure,'You need to select a Peak Sheet','Failed to import data','Icon','error');
                    return;
                end       
                try
                    if app.isXLS
                        [app.DataTable,app.PeakTable] = readDataXLapp(app.xlFullPathfilename,DataSheet=app.DataName,PeakSheet=app.PeakName,fighandle=app.UIFigure);
                        app.ProjectName = app.xlFullPathfilename; 
                        FileType = 'xls';
                    else
                        [app.DataTable,app.PeakTable] = readDataCSVapp(app.csvFullPathDatafilename,app.csvFullPathPeakfilename,fighandle=app.UIFigure);
                        DataFileName = app.csvFullPathDatafilename;
                        PeakFileName = app.csvFullPathPeakfilename;
                        temp = split(DataFileName,'.');
                        app.DataName = temp{1};
                        temp = split(PeakFileName,'.');
                        app.PeakName = temp{1};
                        app.ProjectName = 'CSV'; 
                        FileType = 'csv';
                    end
                catch ME
                    uialert(app.UIFigure,ME.message,'Failed to import data','Icon','error');
                    reset(app);
                    return;
                end
                app.CallingApp.DataTable = app.DataTable;
                app.CallingApp.PeakTable = app.PeakTable;
                app.CallingApp.ExcelName = app.xlFullPathfilename;
                app.CallingApp.ProjectName = app.Filename;
                app.CallingApp.DataName = app.DataName;
                app.CallingApp.PeakName = app.PeakName;
                app.CallingApp.FileType = FileType;
                app.CallingApp.ImportSuccess = true;
                app.CallingApp.CurrentFolder = app.CurrentFolder;
%                 app.CallingApp.Restart();
            UIFigureCloseRequest(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.TidyConvertApp)
            delete(app)      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.CSVPanel = uipanel(app.UIFigure);
            app.CSVPanel.Title = 'CSV:';
            app.CSVpeakLabel = uilabel(app.CSVPanel);
            app.CSVpeakLabel.Text = '';
            app.ChoosePeakCSVButton = uibutton(app.CSVPanel, 'push');
            app.ChoosePeakCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosePeakCSVButtonPushed, true);
            app.ChoosePeakCSVButton.Text = 'Select Feature CSV';
            app.CSVdataLabel = uilabel(app.CSVPanel);
            app.CSVdataLabel.Text = '';
            app.ChooseDataCSVButton = uibutton(app.CSVPanel, 'push');
            app.ChooseDataCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDataCSVButtonPushed, true);
            app.ChooseDataCSVButton.Text = 'Select Data CSV';
            app.ExcelPanel = uipanel(app.UIFigure);
            app.ExcelPanel.Title = 'Excel:';
            app.DataTableDropDownLabel = uilabel(app.ExcelPanel);
            app.DataTableDropDownLabel.Text = 'DataTable:';
            app.FeatureTableDropDownLabel = uilabel(app.ExcelPanel);
            app.FeatureTableDropDownLabel.Text = 'FeatureTable:';
            app.FeatureTableDropDown = uidropdown(app.ExcelPanel);
            app.FeatureTableDropDown.Items = {};
            app.FeatureTableDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureTableDropDownValueChanged, true);
            app.FeatureTableDropDown.Value = {};
            app.DataTableDropDown = uidropdown(app.ExcelPanel);
            app.DataTableDropDown.Items = {};
            app.DataTableDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTableDropDownValueChanged, true);
            app.DataTableDropDown.Value = {};
            app.ExcelFileLabel = uilabel(app.ExcelPanel);
            app.ExcelFileLabel.Text = '';
            app.ChooseExcelButton = uibutton(app.ExcelPanel, 'push');
            app.ChooseExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseExcelButtonPushed, true);
            app.ChooseExcelButton.Text = 'Select Excel File';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'TidyMetqcx.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportDataFeatureFile(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end