classdef QCRSCconfigExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BlankRatioPanel               matlab.ui.container.Panel
        RelativeLODEditField          matlab.ui.control.NumericEditField
        ThresholdLabel                matlab.ui.control.Label
        BlankRatioMethodDropDown      matlab.ui.control.DropDown
        MethodDropDownLabel_2         matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        StatisticsBeforeAfter95CIAfterPanel  matlab.ui.container.Panel
        sampleRSDLabel                matlab.ui.control.Label
        SampleRSDBeforeAfterData      matlab.ui.control.Label
        blankRatioLabel               matlab.ui.control.Label
        BlankRatioData                matlab.ui.control.Label
        qcRSDLabel                    matlab.ui.control.Label
        QCRSDBeforeAfterData          matlab.ui.control.Label
        dRatioLabel                   matlab.ui.control.Label
        dRatioBeforeAfterData         matlab.ui.control.Label
        refRSDLabel                   matlab.ui.control.Label
        RefRSDBeforeAfterData         matlab.ui.control.Label
        OutlierDetectionPanel         matlab.ui.container.Panel
        OutlierPosthocDropDown        matlab.ui.control.DropDown
        PosthocDropDownLabel          matlab.ui.control.Label
        OutlierApplyButton            matlab.ui.control.Button
        OutlierCISwitch               matlab.ui.control.Switch
        MethodDropDownLabel           matlab.ui.control.Label
        OutlierMethodDropDown         matlab.ui.control.DropDown
        FeaturePanel                  matlab.ui.container.Panel
        RESETButton                   matlab.ui.control.Button
        ViewFeatureDictionaryButton   matlab.ui.control.Button
        LOADConfigButton              matlab.ui.control.Button
        SAVEConfigButton              matlab.ui.control.Button
        FeatureEditField              matlab.ui.control.EditField
        FeatureLabel                  matlab.ui.control.Label
        FeatureDropDown               matlab.ui.control.DropDown
        BatchDropDown                 matlab.ui.control.DropDown
        LogTransformCheckBox          matlab.ui.control.CheckBox
        IgnoreBlanksCheckBox          matlab.ui.control.CheckBox
        RemoveZerosCheckBox           matlab.ui.control.CheckBox
        BatchDropDownLabel            matlab.ui.control.Label
        FeatureDropDownLabel          matlab.ui.control.Label
        RandomFeatureButton           matlab.ui.control.Button
        Panel_Optimise                matlab.ui.container.Panel
        ModeDropDown                  matlab.ui.control.DropDown
        ModeDropDownLabel             matlab.ui.control.Label
        CorrectionSwitch              matlab.ui.control.Switch
        CrossValidationDropDown       matlab.ui.control.DropDown
        CrossValidationDropDownLabel  matlab.ui.control.Label
        grangeEditField               matlab.ui.control.EditField
        gammarangeLabel               matlab.ui.control.Label
        OptimiseButton                matlab.ui.control.Button
        MCrepsLabel                   matlab.ui.control.Label
        MCrepsEditField               matlab.ui.control.NumericEditField
        Panel_CVplot                  matlab.ui.container.Panel
        gammavalueLabel               matlab.ui.control.Label
        gammaValueSlider              matlab.ui.control.Slider
        UIAxesMSE                     matlab.ui.control.UIAxes
        Panel_BeforeAfter             matlab.ui.container.Panel
        UIAxesBefore                  matlab.ui.control.UIAxes
        UIAxesAfter                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CurrentFolder   %
        DataTable       % Description
        FeatureTable       % Feature Table - primarliy to access P.UID
        ProjectName     % Project Name
        ExcelName       % Full Path of Excel File.
        DataName        
        FeatureName
        ImportSuccess
        FileType        % csv or xls
        dotSize
    end
    
    properties (Access = private)
        
        CallerApp
        SpreadsheetApp  % popup app to view (edit) Data & Feature tables       
                
        feature            % Name of feature selected to view (e.g. CP1002) 
        feature_old        % Previous feature
        
        jsonTxt         % current json config.
        jsonDefault     % default json config for use during reset.
        baseConfig      % Data Structure containing all the configuration options
        configFilename  % Config filename
        
        y               % raw metabolite feature data
        t               % injection order
        isQC            % y data that is a pooled QC
        isSample        % y data that is a biological sample
        isBlank         % y data that is a blank sample
        isReference     % y data that a reference (i.e. external test)
        SampleID 
        SampleType
        
        yqc             % parsed QC-only metaoblite feature data
        tqc             % parsed QC-only injection order numbers
        toutlier        % timepoints in which yqc is considered an outlier
        
        gammaRange      % range of values used to search for the optimal spline fit to tqc vs yqc
        epsilon         % scaling factor to make the CPAPS algorithm tractable.
        gammaVal        % optimal smoothing value
        cvMse           % cross validation Mean Square Error for the spline fit
        minVal          % minimum value of cvMse
        
        yspline         % the optimal spline fit
        z               % the corrected y data z=f(y) where f = spline model.
        mpv             % mean feature QC value to "reinflate" the corrected data
        
        h_cvMSE         % figure handle for the cvMSE plot
        h_Before        % figure handle for the "before" correction spline (yspline)
        h_Before1       % figure handle for the "before"correction data
        h_Before2       % figure handle for the "before"correction data
        h_Before3       % figure handle for the "before"correction data
        h_Before4       % figure handle for the "before"correction data
        h_Before5       % figure handle for the "before"correction data
        h_After1        % figure handle for the "after" correction data
        h_After2        % figure handle for the "after" correction data
        h_After3        % figure handle for the "after" correction data
        h_After4        % figure handle for the "after" correction data
        h_After5        % figure handle for the "after" correction data
        
        InsufficientDataFlag % flag to abort any atempt at correction when low number of QCs
    end
    
    methods (Access = private)
        
        % helper function to select the correct feature (or a random feature)
        
        function getFeature(app)
            app.feature_old = app.feature;
            app.feature = app.FeatureDropDown.Value;
            if strcmp(app.feature,'Random')
                idx = randperm(length(app.FeatureTable.UID)-1)+1;
                app.feature = app.FeatureTable.UID{idx(1)};
            end
        end


        % helper function to call external OutlierFilter function.
        
        function change = OutlierDetect(app)
            
            old = length(app.toutlier);
            try
                switch app.OutlierMethodDropDown.Value
                    case 'None'
                        app.toutlier = [];
                    case 'Percentile'
                        [app.tqc,app.yqc,app.toutlier] = OutlierFilter(app.tqc,app.yqc,'prctile',app.OutlierCISwitch.Value);
                    case 'Linear'
                        [app.tqc,app.yqc,app.toutlier] = OutlierFilter(app.tqc,app.yqc,'poly1',app.OutlierCISwitch.Value);
                    case 'Quadratic'
                        [app.tqc,app.yqc,app.toutlier] = OutlierFilter(app.tqc,app.yqc,'poly2',app.OutlierCISwitch.Value);
                    case 'Cubic'
                        [app.tqc,app.yqc,app.toutlier] = OutlierFilter(app.tqc,app.yqc,'poly3',app.OutlierCISwitch.Value);
                    otherwise
                        error('This outlier method does not exist');
                end
            catch
               app.toutlier = [];
            end
         
            if length(app.toutlier) == old
                change = false;
            else
                change = true;
            end
            
        end
        
        % meat and potatos of the app. 
        % helper function to call optimiseCSAPS
        
        function optQCRSC(app)       
                                                
            switch app.CrossValidationDropDown.Value
                case '3-Fold', nfold = 3;
                case '5-Fold', nfold = 5;
                case '7-Fold', nfold = 7;
                case 'Leaveout', nfold = -1;
            end
            
            switch app.ModeDropDown.Value
                case 'Sample'
                    app.gammaVal = 10000; % code to trigger NoQC regession
                    app.epsilon = NaN;
                    app.cvMse = 0;
                    app.minVal = 0;
                case 'Mean'
                    app.gammaVal = NaN;
                    app.epsilon = NaN;
                    app.cvMse = 0;
                    app.minVal = 0;
                case 'Linear'
                    %app.gammaVal = 11;
                    %avDist = median(app.tqc(2:end) - app.tqc(1:end-1));
                    %app.epsilon = avDist^3/16;
                    app.gammaVal = 1000; % code to trigger Robust regession
                    app.epsilon = NaN;
                    app.cvMse = 0;
                    app.minVal = 0;
                otherwise
                    [app.gammaVal,app.epsilon,app.cvMse,app.minVal] = ...
                    optimiseCSAPS(app.tqc,app.yqc,app.gammaRange,nfold,app.MCrepsEditField.Value); 
            end
            if length(app.gammaRange) == 1 || ~strcmp(app.ModeDropDown.Value,'Spline')
                app.gammaValueSlider.Enable = 'off';
            else
                app.gammaValueSlider.Enable = 'on';
                app.gammaValueSlider.Limits = [min(app.gammaRange),max(app.gammaRange)];
                app.gammaValueSlider.Value = app.gammaVal;         
            end
            
        end
        
        % helper function to call external calcStats
        % calculates RSD, D-Ratio and Blank-Ratio
        
        function displayStats(app)
            
            found = ismember(app.t,app.toutlier);
            yy = app.y;
            yy(found) = nan;
            
            isLog = app.LogTransformCheckBox.Value;
            BRM = app.baseConfig.BlankRatioMethod;

            
            [QCrsdBefore,~,~,SAMPLErsdBefore,~,~,REFrsdBefore,~,~,dRatioBefore,blankRatioBefore] = calcStats(yy,app.isQC,app.isSample,app.isBlank,app.isReference,Logged=isLog,BlankRatioMethod=BRM);
            [QCrsdAfter,QCrsdAfterlower,QCrsdAfterupper,SAMPLErsdAfter,SAMPLErsdAfterlower,SAMPLErsdAfterupper,REFrsdAfter,REFrsdAfterlower,REFrsdAfterupper,dRatioAfter,blankRatioAfter] = calcStats(app.z,app.isQC,app.isSample,app.isBlank,app.isReference,Logged=isLog,BlankRatioMethod=BRM);
                     
            if isnan(QCrsdBefore)
                app.QCRSDBeforeAfterData.Text = sprintf('Insufficient Data');           
            else
                app.QCRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',QCrsdBefore,QCrsdAfter,QCrsdAfterlower,QCrsdAfterupper);           
            end 

            if isnan(SAMPLErsdBefore)
                app.SampleRSDBeforeAfterData.Text = sprintf('Insufficient Data');           
            else
                app.SampleRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',SAMPLErsdBefore,SAMPLErsdAfter,SAMPLErsdAfterlower,SAMPLErsdAfterupper);           
            end
            
            if isnan(REFrsdBefore)
                app.RefRSDBeforeAfterData.Text = sprintf('Insufficient Data');           
            else
                app.RefRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',REFrsdBefore,REFrsdAfter,REFrsdAfterlower,REFrsdAfterupper);           
            end

            if isnan(dRatioBefore)
                app.dRatioBeforeAfterData.Text = sprintf('Insufficient Data');
            else
                app.dRatioBeforeAfterData.Text = sprintf('[%.2f] %.2f',dRatioBefore,dRatioAfter);
            end

            if isnan(blankRatioBefore)
                app.BlankRatioData.Text = sprintf('Insufficient Data');
            else
                app.BlankRatioData.Text = sprintf('[%.2f] %.2f',blankRatioBefore,blankRatioAfter);
            end
            
        end
       
        % plotting the results of the optimisation
        % cvMSE = cross validated Mean Squared Error of fit.
        
        function plot_cvMSE(app)
            
            axInnerPos = app.UIAxesMSE.InnerPosition;                  
            
            cla(app.UIAxesMSE);
            plot(app.UIAxesMSE,app.gammaRange,app.cvMse,'b-o','linewidth',1,'MarkerFaceColor','w','MarkerSize',4);
            hold(app.UIAxesMSE,'on');
            plot(app.UIAxesMSE,app.gammaVal,app.minVal,'ko','linewidth',1,'MarkerFaceColor',[0.7,0.7,0.7],'MarkerSize',8);
            app.h_cvMSE = plot(app.UIAxesMSE,app.gammaVal,app.minVal,'ko','linewidth',1,'MarkerFaceColor','r','MarkerSize',6);
            if numel(app.gammaRange) ==1
                xlim(app.UIAxesMSE,[app.gammaVal-1,app.gammaVal+1]);
                ylim(app.UIAxesMSE,[app.minVal,2*app.minVal]);
            elseif numel(app.cvMse) ==1
                xlim(app.UIAxesMSE,[min(app.gammaRange),max(app.gammaRange)]);
                ylim(app.UIAxesMSE,[-1,1]);
                if strcmp(app.ModeDropDown.Value,'Spline')
                    text(app.UIAxesMSE,mean(app.gammaRange),0.5,{'Nothing to optimise: ';'Too few QCs for Spline Correction'},"HorizontalAlignment","center");
                else
                    text(app.UIAxesMSE,mean(app.gammaRange),0.5,{'Nothing to optimise: ';[app.ModeDropDown.Value,' Correction']},"HorizontalAlignment","center");
                end
            else
                xlim(app.UIAxesMSE,[min(app.gammaRange),max(app.gammaRange)]);
                ylim(app.UIAxesMSE,[min(app.cvMse),max(app.cvMse)]); 
            end
                
            hold(app.UIAxesMSE,'off');
            innerPosDiff = axInnerPos - app.UIAxesMSE.InnerPosition;
            
            app.UIAxesMSE.Position = app.UIAxesMSE.Position + innerPosDiff;

        end
        
        %Plot the data and optimal spline before correction
        % note the handles are required for the interactive changes to gamma
        
        function plot_before(app)
            
            isLog = app.LogTransformCheckBox.Value;
            found = ismember(app.t,app.toutlier);
            youtlier = app.y(found);

            if ismac
                dot_size = 7;
                lw = 2;
                ms = 6;
            else
                dot_size = 5;
                lw = 1.5;
                ms = 4;
            end

            temp = app.y(app.isBlank);
            if isempty(temp)
                temp = NaN;
            else
                if isLog
                    temp = power(10,temp);
                    temp = max(temp,[],1,'omitnan');
                    temp = temp*app.baseConfig.RelativeLOD;
                    temp = log10(temp);
                else
                    temp = max(temp,[],1,'omitnan');
                    temp = temp*app.baseConfig.RelativeLOD;
                end
            end
            
          
            cla(app.UIAxesBefore);
            app.h_Before5 = line(app.UIAxesBefore,[app.t(1) app.t(end)],[temp temp],'LineStyle',':','Color','m','LineWidth',lw-1);
            datatip(app.h_Before5,'Visible','off');
            app.h_Before5.DataTipTemplate.DataTipRows(1) = dataTipTextRow('blank threshold','');
            app.h_Before5.DataTipTemplate.DataTipRows(2) = [];
            hold(app.UIAxesBefore,'on');           
            app.h_Before1 = plot(app.UIAxesBefore,app.t(~app.isQC),app.y(~app.isQC),'ko','MarkerFaceColor',[0,191,255]./255,'MarkerSize',dot_size);   
            app.h_Before1.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(~app.isQC));
            app.h_Before1.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.SampleType(~app.isQC));            
            plot(app.UIAxesBefore,app.toutlier,youtlier,'ks','MarkerSize',dot_size+4,'MarkerFaceColor','k','LineWidth',lw);
            app.h_Before2 = plot(app.UIAxesBefore,app.t(app.isQC),app.y(app.isQC),'ko','MarkerFaceColor','r','MarkerSize',dot_size);
            if sum(app.isQC) ~= 0
                app.h_Before2.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(app.isQC));
                app.h_Before2.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.SampleType(app.isQC));
            end
            app.h_Before3 = plot(app.UIAxesBefore,app.t(app.isBlank),app.y(app.isBlank),'ko','MarkerFaceColor','m','MarkerSize',dot_size); 
            if sum(app.isBlank) ~= 0
                app.h_Before3.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(app.isBlank));
                app.h_Before3.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleType:',app.SampleType(app.isBlank));   
            end
            app.h_Before4 = plot(app.UIAxesBefore,app.t(app.isReference),app.y(app.isReference),'ko','MarkerFaceColor','y','MarkerSize',dot_size);           
            if sum(app.isReference) ~= 0
                app.h_Before4.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(app.isReference));
                app.h_Before4.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.SampleType(app.isReference));      
            end
            plot(app.UIAxesBefore,app.t,app.yspline,'--','MarkerSize',ms,'LineWidth',lw-1,'color',[0.7,0.7,0.7]);
            app.h_Before = plot(app.UIAxesBefore,app.t,app.yspline,'r--','MarkerSize',ms,'LineWidth',lw-1);
            app.h_Before.DataTipTemplate.DataTipRows(1) = dataTipTextRow('correction spline','');
            app.h_Before.DataTipTemplate.DataTipRows(2) = [];
            axis(app.UIAxesBefore,[min(app.t)-1, max(app.t)+1, 0.99*nanmin([app.y;app.z]),1.01*nanmax([app.y;app.z])]);
            hold(app.UIAxesBefore,'off');
            title(app.UIAxesBefore,[num2str(app.feature),': Original'])
        end
        
        %Plot the data and optimal spline after correction
        % note the handles are required for the interactive changes to gamma
        
        function plot_after(app)

            if ismac
                dot_size = 7;
                lw = 2;
                ms = 6;
            else
                dot_size = 5;
                lw = 1.5;
                ms = 4;
            end
            
            cla(app.UIAxesAfter); 
            app.h_After5 = line(app.UIAxesAfter,[app.t(1) app.t(end)],[app.mpv app.mpv],'LineStyle','--','Color','r','LineWidth',lw-1);
            datatip(app.h_After5,'Visible','off');
            app.h_After5.DataTipTemplate.DataTipRows(1) = dataTipTextRow('QC Grand Mean','');
            app.h_After5.DataTipTemplate.DataTipRows(2) = [];
            hold(app.UIAxesAfter,'on');
            plot(app.UIAxesAfter,app.t(~app.isQC),app.z(~app.isQC),'o','MarkerSize',dot_size,'color','k','MarkerFaceColor',[0.96,0.96,0.96]);
            plot(app.UIAxesAfter,app.t(app.isQC),app.z(app.isQC),'o','MarkerSize',dot_size,'color','k','MarkerFaceColor',[0.96,0.96,0.96]);
            app.h_After1 = plot(app.UIAxesAfter,app.t(~app.isQC),app.z(~app.isQC),'ko','MarkerFaceColor',[0,191,255]./255,'MarkerSize',dot_size);
            app.h_After1.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(~app.isQC));
            app.h_After1.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.SampleType(~app.isQC));
            app.h_After2 = plot(app.UIAxesAfter,app.t(app.isQC),app.z(app.isQC),'ko','MarkerFaceColor','r','MarkerSize',dot_size);
            if sum(app.isQC) ~= 0
                app.h_After2.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(app.isQC));
                app.h_After2.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.SampleType(app.isQC));
            end
            app.h_After3 = plot(app.UIAxesAfter,app.t(app.isBlank),app.z(app.isBlank),'ko','MarkerFaceColor','m','MarkerSize',dot_size);
            if sum(app.isBlank) ~= 0
                app.h_After3.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(app.isBlank));
                app.h_After3.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.SampleType(app.isBlank));
            end
            app.h_After4 = plot(app.UIAxesAfter,app.t(app.isReference),app.z(app.isReference),'ko','MarkerFaceColor','y','MarkerSize',dot_size);
            if sum(app.isReference) ~= 0
                app.h_After4.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(app.isReference));
                app.h_After4.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.SampleType(app.isReference));
            end
            axis(app.UIAxesAfter,[min(app.t)-1, max(app.t)+1, 0.99*nanmin([app.y;app.z]),1.01*nanmax([app.y;app.z])]);
            hold(app.UIAxesAfter,'off');
            title(app.UIAxesAfter,[num2str(app.feature),': Corrected'])
        end
        
        % helper function to transfer the data from the baseConfig
        % structure to the GUI
        
        function UpdateConfigGUI(app)
     
            app.LogTransformCheckBox.Value = app.baseConfig.LogTransform;
            app.IgnoreBlanksCheckBox.Value = false;%app.baseConfig.IgnoreBlanks;
            app.RemoveZerosCheckBox.Value = app.baseConfig.RemoveZeros;
            
            app.OutlierMethodDropDown.Value = app.baseConfig.OutlierMethod;
            app.OutlierCISwitch.Value = app.baseConfig.OutlierCI;
            app.OutlierPosthocDropDown.Value = app.baseConfig.OutlierPostHoc;
            
            app.ModeDropDown.Value = app.baseConfig.IntraBatchMode;
            app.grangeEditField.Value = app.baseConfig.QCRSCgammaRange;
            app.CrossValidationDropDown.Value = app.baseConfig.QCRSCcvMethod;
            app.MCrepsEditField.Value = app.baseConfig.QCRSCmcReps;
            app.CorrectionSwitch.Value = app.baseConfig.CorrectionType;
                   
            app.BlankRatioMethodDropDown.Value = app.baseConfig.BlankRatioMethod;
            app.RelativeLODEditField.Value = app.baseConfig.RelativeLOD;

            
        end
        
        % update all the various feature data when the feature name is changed
        % and account for new radio button selection
        % any changes in the FeaturePanel required a new aqusition of data from
        % source Data Table.
        
        function UpdateData(app)
            
            Data = app.DataTable(:,{'Order','QC','SampleID','SampleType','Sample','Blank','Reference','Batch',app.feature});
             
            if app.IgnoreBlanksCheckBox.Value
                Data = Data(Data.Blank == 0,:); 
            end
            keep = Data.Batch == app.BatchDropDown.Value;
            Data = Data(keep,:);
            
            app.y = Data.(app.feature);
            app.t = Data.Order;
            app.isQC = logical(Data.QC);
            app.isSample = logical(Data.Sample);
            app.isBlank = logical(Data.Blank);
            app.isReference = logical(Data.Reference);
            app.SampleID = Data.SampleID;
            app.SampleType = Data.SampleType;
            
           
            if app.RemoveZerosCheckBox.Value
                app.y(app.y == 0) = NaN;
            end
            
            if app.LogTransformCheckBox.Value
                app.y(app.y == 0) = NaN;
                app.RemoveZerosCheckBox.Value = true;
                app.y = log10(app.y);
                ylabel(app.UIAxesBefore,'log_{10}(Feature Value)');
                ylabel(app.UIAxesAfter,'log_{10}(Feature Value)');
            else
                ylabel(app.UIAxesBefore,'Feature Value');
                ylabel(app.UIAxesAfter,'Feature Value');
            end
           
            
            missing = isnan(app.y);
            app.isQC(missing) = false;
            app.tqc = app.t(app.isQC);
            app.yqc = app.y(app.isQC);
            app.mpv = mean(app.yqc);
            
            if length(app.tqc) < 3
                %uialert(app.UIFigure,'Too few QC points to construct a correcton curve. QCRSC ignored','Warning','Icon','warning');
                app.gammaValueSlider.Enable = 'off';
                app.OptimiseButton.Enable = 'off';
                app.OutlierApplyButton.Enable = 'off';
                app.cvMse = [];
                app.InsufficientDataFlag = true;
            else
                app.gammaValueSlider.Enable = 'on';
                app.OptimiseButton.Enable = 'on';
                app.OutlierApplyButton.Enable = 'on';
                app.InsufficientDataFlag = false;
            end
        end
        
        function [outConfig,changed] = updateConfigStruct(app,inConfig)
            outConfig = inConfig;
            outConfig.LogTransform = app.LogTransformCheckBox.Value;
            outConfig.RemoveZeros = app.RemoveZerosCheckBox.Value;        
            outConfig.OutlierMethod = app.OutlierMethodDropDown.Value;
            outConfig.OutlierCI = app.OutlierCISwitch.Value;
            outConfig.OutlierPostHoc = app.OutlierPosthocDropDown.Value;
            outConfig.IntraBatchMode = app.ModeDropDown.Value;
            outConfig.QCRSCgammaRange = app.grangeEditField.Value;
            outConfig.QCRSCcvMethod = app.CrossValidationDropDown.Value;
            outConfig.QCRSCmcReps = app.MCrepsEditField.Value;
            outConfig.CorrectionType = app.CorrectionSwitch.Value;
            outConfig.BlankRatioMethod = app.BlankRatioMethodDropDown.Value;
            outConfig.RelativeLOD = app.RelativeLODEditField.Value;
            changed = ~isequaln(inConfig,outConfig);
        end
    end
    
    methods (Access = public)
            
        function Restart(app)
            ffile = fullfile(app.CurrentFolder,app.ProjectName);
            app.UIFigure.Name =  ffile;
            %app.UIFigure.Name = app.ProjectName;
            app.FeatureDropDown.Items = [app.FeatureTable.UID;'Random'];
            app.BatchDropDown.Items = arrayfun(@(x) ['Batch ',num2str(x)],unique(app.DataTable.Batch),'UniformOutput',false);
            app.BatchDropDown.ItemsData = unique(app.DataTable.Batch);
            RESETButtonPushed(app);         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp)
            app.CallerApp = callerApp;
            
            app.gammavalueLabel.Text = [char(947), ' value'];
            app.gammarangeLabel.Text = [char(947), ' range:'];          
            
            % jsonD = ['{' newline ...
            %     '"LogTransform": true,' newline ...
            %     '"RemoveZeros": true,' newline ...
            %     '"OutlierMethod": "Quadratic",' newline ...
            %     '"OutlierCI": 0.95,' newline ...
            %     '"OutlierPostHoc": "NaN",' newline ...
            %     '"IntraBatchMode": "Spline",' newline ...
            %     '"InterBatchMode": "QC",' newline ... 
            %     '"QCRSCgammaRange": "0:0.5:4",' newline ...
            %     '"QCRSCcvMethod": "5-Fold",' newline ...
            %     '"QCRSCmcReps": 10,' newline ...
            %     '"CorrectionType": "Subtract",' newline ...
            %     '"BlankRatioMethod": "QC",' newline ...
            %     '"RelativeLOD": 1.5,' newline ...
            %     '"ParallelProcess": true ' newline ...
            %     '}'];
            
            if isempty(app.CallerApp.DataTable)
                [app.DataTable,app.FeatureTable] = generateMetabExamplesNew();
                app.ProjectName = 'Demo (25 LC-MS C18+ Features, single batch)';
                [app.DataTable,app.FeatureTable] = validatingDataFeatureTables(app.DataTable,app.FeatureTable,fighandle = app.UIFigure,ColumnWarningFlag=false);
                app.jsonDefault = app.CallerApp.jsonTxt;
                app.configFilename = app.CallerApp.configFilename;
                app.CurrentFolder = app.CallerApp.CurrentFolder;
            else
                app.DataTable = app.CallerApp.DataTable;
                app.FeatureTable = app.CallerApp.FeatureTable(app.CallerApp.preProcKeep,:);
                [app.DataTable,app.FeatureTable] = validatingDataFeatureTables(app.DataTable,app.FeatureTable,fighandle = app.UIFigure,ColumnWarningFlag=false);
                app.jsonDefault = app.CallerApp.jsonTxt;
                app.configFilename = app.CallerApp.configFilename;
                app.ProjectName = app.CallerApp.ProjectName; 
                app.CurrentFolder = app.CallerApp.CurrentFolder;
            end

            ffile = fullfile(app.CurrentFolder,app.ProjectName);
            app.UIFigure.Name =  ffile;
            
            app.DataName = 'Data Table';
            app.FeatureName = 'Feature Dictionary';
            
            app.FeatureDropDown.Items = [app.FeatureTable.UID;'Random'];
            app.BatchDropDown.Items = arrayfun(@(x) ['Batch ',num2str(x)],unique(app.DataTable.Batch),'UniformOutput',false);
            app.BatchDropDown.ItemsData = unique(app.DataTable.Batch);
            
            
            RESETButtonPushed(app);
            
            getFeature(app);
            app.FeatureEditField.Value = app.feature;
            UpdateData(app);
            OutlierDetect(app);
            app.gammaRange = str2num(app.grangeEditField.Value);
            OptimiseButtonPushed(app); 
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.jsonTxt = app.jsonDefault;
            app.baseConfig = jsondecode(app.jsonTxt);
            validateConfig(app.baseConfig); 
            app.BatchDropDown.Value = app.BatchDropDown.ItemsData(1);
            app.FeatureDropDown.Value = app.FeatureDropDown.Items(1);
            %app.baseConfig = createDefaultConfigStruct();
            UpdateConfigGUI(app);            
            GetFeatureUpdateData(app);
        end

        % Button pushed function: ViewFeatureDictionaryButton
        function ViewFeatureDictionaryButtonPushed(app, event)
          app.ViewFeatureDictionaryButton.Enable = 'off';                
          app.SpreadsheetApp = TidyFeatureTableView(app, app.FeatureTable, Name=app.ProjectName,FeatureName=app.FeatureName);
          
        end

        % Button pushed function: LOADConfigButton
        function LOADConfigButtonPushed(app, event)
            oldConfig = app.baseConfig;
            oldJSON = app.jsonTxt;
            
            app.UIFigure.Visible = 'off'; 
            [file, path] = uigetfile('.json');
            app.UIFigure.Visible = 'on'; 
            
            if ~isequal(file,0)
                app.jsonTxt = fileread(fullfile(path,file));
                app.baseConfig = jsondecode(app.jsonTxt);
                try
                    validateConfig(app.baseConfig);
                    app.jsonDefault = app.jsonTxt;
                    app.configFilename = file;
                    UpdateConfigGUI(app);
                catch ME
                    uialert(app.UIFigure,{ME.message;char('Failed to import config file.')},'Error: Config Error','Icon','error');
                    app.jsonTxt = oldJSON;
                    app.baseConfig = oldConfig;
                end
            else
                uialert(app.UIFigure,'Failed to import config file','Config Error','Icon','error');
            end
        end

        % Button pushed function: SAVEConfigButton
        function SAVEConfigButtonPushed(app, event)
            oldConfig = app.baseConfig;
            app.baseConfig = updateConfigStruct(app,app.baseConfig);
 
            app.UIFigure.Visible = 'off';
            [file,path]=uiputfile('.json','Save QCRSC config file',app.configFilename);
            app.UIFigure.Visible = 'on';

            if ~isequal(file,0)
                %cfg = jsondecode(app.jsonTxt);
                jsonTxtX = jsonencode(app.baseConfig,'PrettyPrint',true);
                fid=fopen(fullfile(path,file),'w');
                fprintf(fid, jsonTxtX);
                fclose(fid); 
            else
                uialert(app.UIFigure,'Failed to save config file','Error','Icon','error');
                app.baseConfig = oldConfig;
            end
            
        end

        % Value changed function: FeatureEditField
        function FeatureEditFieldValueChanged(app, event)
            oldvalue = app.FeatureDropDown.Value;
            value = app.FeatureEditField.Value;
            if ~ismember(value,app.FeatureDropDown.Items)
                uialert(app.UIFigure,'Feature does not exist','Warning','Icon','warning');
                %warndlg('Feature does not exist','Warning');
                value = oldvalue;
            end
            app.FeatureDropDown.Value = value;
            GetFeatureUpdateData(app);
        end

        % Button pushed function: RandomFeatureButton
        function RandomFeatureButtonPushed(app, event)
            app.FeatureDropDown.Value = 'Random';
            GetFeatureUpdateData(app);
        end

        % Value changed function: FeatureDropDown
        function GetFeatureUpdateData(app, event)
            %value = app.FeatureDropDown.Value;
            getFeature(app);
            app.FeatureEditField.Value = app.feature;
            UpdateDataChanged(app);
            
            if ~isempty(app.SpreadsheetApp)         
                if isvalid(app.SpreadsheetApp)
                    highlightrow(app.SpreadsheetApp,app.feature);
                end
            end
        end

        % Value changed function: BatchDropDown
        function BatchDropDownValueChanged(app, event)
            UpdateDataChanged(app);
        end

        % Value changed function: IgnoreBlanksCheckBox, 
        % ...and 2 other components
        function UpdateDataChanged(app, event)
            UpdateData(app);
            OutlierDetect(app);
            OptimiseButtonPushed(app);
        end

        % Callback function: OutlierApplyButton, OutlierCISwitch, 
        % ...and 1 other component
        function OutlierApplyButtonPushed(app, event)
            UpdateData(app);
            changed = OutlierDetect(app);
            if changed
                OptimiseButtonPushed(app);
            end
        end

        % Callback function: CrossValidationDropDown, MCrepsEditField, 
        % ...and 3 other components
        function OptimiseButtonPushed(app, event)
           if app.InsufficientDataFlag  
                app.yspline = app.mpv*ones(length(app.y),1);
                app.z = app.y;
                cla(app.UIAxesMSE);
                xlim(app.UIAxesMSE,[-1,1]);
                ylim(app.UIAxesMSE,[-1,1]);
                text(app.UIAxesMSE,0,0.5,{'Too Few QC samples';'QCRSC ignored'},"HorizontalAlignment","center");

                displayStats(app);
           else
                app.gammaRange = str2num(app.grangeEditField.Value);
                optQCRSC(app);
                plot_cvMSE(app);
                [app.z,app.yspline] = QCRSC3(app.t,app.y,app.isQC,app.isSample,app.mpv,app.epsilon,app.gammaVal,...
                    app.toutlier,app.CorrectionSwitch.Value,app.OutlierPosthocDropDown.Value); 
                %app.z(app.isBlank) = app.y(app.isBlank);
                displayStats(app);
            end
            plot_before(app);
            plot_after(app);
            linkaxes([app.UIAxesBefore app.UIAxesAfter],'xy');
        end

        % Value changed function: CorrectionSwitch, OutlierPosthocDropDown
        function rerunQCRSC(app, event)
            [app.z,app.yspline] = QCRSC3(app.t,app.y,app.isQC,app.isSample,app.mpv,app.epsilon,app.gammaVal,...
                app.toutlier,app.CorrectionSwitch.Value,app.OutlierPosthocDropDown.Value);
            %app.z(app.isBlank) = app.y(app.isBlank);
            displayStats(app);
            plot_after(app);
        end

        % Value changing function: gammaValueSlider
        function gammaValueSliderValueChanging(app, event)
            changingValue = event.Value;
            app.h_cvMSE.YData = interp1(app.gammaRange,app.cvMse,changingValue);
            app.h_cvMSE.XData = changingValue;
            
            pp = 1/(1+app.epsilon*10^(changingValue));
            yy = csaps(app.tqc,app.yqc,pp,app.t);
            app.z = (app.y-yy)+app.mpv;
            app.h_Before.YData = yy;
            
            app.h_After1.YData = app.z(~app.isQC);
            app.h_After2.YData = app.z(app.isQC);
            if ~isempty(app.z(app.isBlank)), app.h_After3.YData = app.z(app.isBlank); end
            if ~isempty(app.z(app.isReference)), app.h_After4.YData = app.z(app.isReference); end
            
            displayStats(app)
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            [app.baseConfig,changed] = updateConfigStruct(app,app.baseConfig);
            if changed
                res = uiconfirm(app.UIFigure,'Do you want to transfer this configuration to the main application?','Confirm Action','Options',{'Yes','No'});
                if strcmp(res,'Yes')
                    app.baseConfig = updateConfigStruct(app,app.baseConfig);            
                    jsonText = jsonencode(app.baseConfig,'PrettyPrint',true);
                    app.CallerApp.jsonTxt = jsonText;
                    app.CallerApp.baseConfig = app.baseConfig;
                    app.CallerApp.jsonView = strsplit(jsonText,'\n')';
                    app.CallerApp.configFileTextArea.Value = app.CallerApp.jsonView;
                end
            end
            delete(app.SpreadsheetApp)           
            delete(app)
        end

        % Value changed function: BlankRatioMethodDropDown
        function BlankRatioMethodDropDownValueChanged(app, event)
            app.baseConfig.BlankRatioMethod = app.BlankRatioMethodDropDown.Value;
            displayStats(app);
        end

        % Value changed function: RelativeLODEditField
        function RelativeLODEditFieldValueChanged(app, event)
            app.baseConfig.RelativeLOD = app.RelativeLODEditField.Value;            
            plot_before(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_BeforeAfter = uipanel(app.UIFigure);
            app.UIAxesAfter = uiaxes(app.Panel_BeforeAfter);
            title(app.UIAxesAfter, 'Corrected')
            xlabel(app.UIAxesAfter, 'Injection order')
            ylabel(app.UIAxesAfter, 'log_{10}(Feature Value)')
            zlabel(app.UIAxesAfter, 'Z')
            app.UIAxesBefore = uiaxes(app.Panel_BeforeAfter);
            title(app.UIAxesBefore, 'Original')
            xlabel(app.UIAxesBefore, 'Injection order')
            ylabel(app.UIAxesBefore, 'log_{10}(Feature Value)')
            zlabel(app.UIAxesBefore, 'Z')
            app.Panel_CVplot = uipanel(app.UIFigure);
            app.Panel_CVplot.Tooltip = {'A plot of the cross-validation Mean Sqaure Error (cvMSE) for the range of smoothing values. The minimum cvMSE indicates the best smoothing value.'};
            app.Panel_CVplot.Title = 'Check Overfitting:';
            app.UIAxesMSE = uiaxes(app.Panel_CVplot);
            title(app.UIAxesMSE, '\gamma optimisation')
            ylabel(app.UIAxesMSE, 'cvMSE')
            zlabel(app.UIAxesMSE, 'Z')
            app.UIAxesMSE.XTickLabel = '';
            app.gammaValueSlider = uislider(app.Panel_CVplot);
            app.gammaValueSlider.Limits = [-1 4];
            app.gammaValueSlider.ValueChangingFcn = createCallbackFcn(app, @gammaValueSliderValueChanging, true);
            app.gammaValueSlider.Tooltip = {'Move the slider to see how changing the smoothing value changes the correction curve, which in turn changes the corrected data.'};
            app.gammavalueLabel = uilabel(app.Panel_CVplot);
            app.gammavalueLabel.Text = 'g value';
            app.Panel_Optimise = uipanel(app.UIFigure);
            app.Panel_Optimise.Title = 'QCRSC:';
            app.MCrepsEditField = uieditfield(app.Panel_Optimise, 'numeric');
            app.MCrepsEditField.Limits = [1 100];
            app.MCrepsEditField.ValueChangedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.MCrepsEditField.Tooltip = {'The number of Monte Carlo (random) resamples of the k-fold cross validation. The resulting cvMSE the mean of the generated set of cvMSEs.'};
            app.MCrepsEditField.Value = 20;
            app.MCrepsLabel = uilabel(app.Panel_Optimise);
            app.MCrepsLabel.Text = 'MCreps:';
            app.OptimiseButton = uibutton(app.Panel_Optimise, 'push');
            app.OptimiseButton.ButtonPushedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.OptimiseButton.Text = 'Optimise';
            app.gammarangeLabel = uilabel(app.Panel_Optimise);
            app.gammarangeLabel.Text = 'g range:';
            app.grangeEditField = uieditfield(app.Panel_Optimise, 'text');
            app.grangeEditField.ValueChangedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.grangeEditField.Tooltip = {'The range of values to search to determine to optimal correction curve.'; 'A value >=4 results in a curve equivalent to a linear regression. '; 'A value < 0 results in a highly nonlinear curve.'};
            app.grangeEditField.Value = '0:0.5:4';
            app.CrossValidationDropDownLabel = uilabel(app.Panel_Optimise);
            app.CrossValidationDropDownLabel.Text = 'Cross Validation:';
            app.CrossValidationDropDown = uidropdown(app.Panel_Optimise);
            app.CrossValidationDropDown.Items = {'3-Fold', '5-Fold', '7-Fold', 'Leaveout'};
            app.CrossValidationDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.CrossValidationDropDown.Tooltip = {'The cross-validation method used to avoid overfitting the correction curve to the QC data. ''3-Fold'' is recommended.'};
            app.CrossValidationDropDown.Value = '5-Fold';
            app.CorrectionSwitch = uiswitch(app.Panel_Optimise, 'slider');
            app.CorrectionSwitch.Items = {'Subtract', 'Divide'};
            app.CorrectionSwitch.ValueChangedFcn = createCallbackFcn(app, @rerunQCRSC, true);
            app.CorrectionSwitch.Tooltip = {'Subtract the correction curve from all of the input data or or divide all of the input data by the correction curve.'};
            app.CorrectionSwitch.Value = 'Subtract';
            app.ModeDropDownLabel = uilabel(app.Panel_Optimise);
            app.ModeDropDownLabel.Text = 'Mode:';
            app.ModeDropDown = uidropdown(app.Panel_Optimise);
            app.ModeDropDown.Items = {'Spline', 'Linear', 'Mean', 'Sample'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.ModeDropDown.Tooltip = {'Three correction modes. "Spline" is the default QCRSC algorithm that requires optimisation of the smoothing parameter. "Linear" is a simple (interpolated) linear regression based on the QC values & as such requires no smoothing optimisation. "Mean" equalises the QC mean across batches & ignores within batch systematic change. "Sample"  performs a linear regression based on only the Samples (the QCs are not used to model the regression).'};
            app.ModeDropDown.Value = 'Spline';
            app.FeaturePanel = uipanel(app.UIFigure);
            app.RandomFeatureButton = uibutton(app.FeaturePanel, 'push');
            app.RandomFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @RandomFeatureButtonPushed, true);
            app.RandomFeatureButton.Text = 'Random Feature';
            app.FeatureDropDownLabel = uilabel(app.FeaturePanel);
            app.FeatureDropDownLabel.Text = 'Feature:';
            app.BatchDropDownLabel = uilabel(app.FeaturePanel);
            app.BatchDropDownLabel.Text = 'Batch:';
            app.RemoveZerosCheckBox = uicheckbox(app.FeaturePanel);
            app.RemoveZerosCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDataChanged, true);
            app.RemoveZerosCheckBox.Text = 'Remove Zeros';
            app.IgnoreBlanksCheckBox = uicheckbox(app.FeaturePanel);
            app.IgnoreBlanksCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDataChanged, true);
            app.IgnoreBlanksCheckBox.Text = 'Ignore Blanks';
            app.IgnoreBlanksCheckBox.Value = true;
            app.LogTransformCheckBox = uicheckbox(app.FeaturePanel);
            app.LogTransformCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDataChanged, true);
            app.LogTransformCheckBox.Text = 'Log Transform';
            app.LogTransformCheckBox.Value = true;
            app.BatchDropDown = uidropdown(app.FeaturePanel);
            app.BatchDropDown.Items = {'No Data'};
            app.BatchDropDown.ValueChangedFcn = createCallbackFcn(app, @BatchDropDownValueChanged, true);
            app.BatchDropDown.Value = 'No Data';
            app.FeatureDropDown = uidropdown(app.FeaturePanel);
            app.FeatureDropDown.Items = {'No Data'};
            app.FeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @GetFeatureUpdateData, true);
            app.FeatureDropDown.Value = 'No Data';
            app.FeatureLabel = uilabel(app.FeaturePanel);
            app.FeatureLabel.Text = 'Feature:';
            app.FeatureEditField = uieditfield(app.FeaturePanel, 'text');
            app.FeatureEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureEditFieldValueChanged, true);
            app.SAVEConfigButton = uibutton(app.FeaturePanel, 'push');
            app.SAVEConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEConfigButtonPushed, true);
            app.SAVEConfigButton.Visible = 'off';
            app.SAVEConfigButton.Text = 'SAVE Config';
            app.LOADConfigButton = uibutton(app.FeaturePanel, 'push');
            app.LOADConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LOADConfigButtonPushed, true);
            app.LOADConfigButton.Visible = 'off';
            app.LOADConfigButton.Text = 'LOAD Config';
            app.ViewFeatureDictionaryButton = uibutton(app.FeaturePanel, 'push');
            app.ViewFeatureDictionaryButton.ButtonPushedFcn = createCallbackFcn(app, @ViewFeatureDictionaryButtonPushed, true);
            app.ViewFeatureDictionaryButton.Text = 'View Feature Dictionary';
            app.RESETButton = uibutton(app.FeaturePanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.OutlierDetectionPanel = uipanel(app.UIFigure);
            app.OutlierDetectionPanel.Title = 'Outlier Detection:';
            app.OutlierMethodDropDown = uidropdown(app.OutlierDetectionPanel);
            app.OutlierMethodDropDown.Items = {'None', 'Percentile', 'Linear', 'Quadratic', 'Cubic'};
            app.OutlierMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @OutlierApplyButtonPushed, true);
            app.OutlierMethodDropDown.Tooltip = {'The ''linear''/quadratic/cubic'' options fit a simple polynomial function to the QC data using robust least squares regression. Points outside the population confidence interval are deemed outliers. The ''percentile'' option implements the standard non-parametric 1.5*IQR outlier detection method on the QC samples.'};
            app.OutlierMethodDropDown.Value = 'Percentile';
            app.MethodDropDownLabel = uilabel(app.OutlierDetectionPanel);
            app.MethodDropDownLabel.Text = 'Method:';
            app.OutlierCISwitch = uiswitch(app.OutlierDetectionPanel, 'slider');
            app.OutlierCISwitch.Items = {'95%CI', '99%CI'};
            app.OutlierCISwitch.ItemsData = [0.95 0.99];
            app.OutlierCISwitch.ValueChangedFcn = createCallbackFcn(app, @OutlierApplyButtonPushed, true);
            app.OutlierCISwitch.Value = 0.99;
            app.OutlierApplyButton = uibutton(app.OutlierDetectionPanel, 'push');
            app.OutlierApplyButton.ButtonPushedFcn = createCallbackFcn(app, @OutlierApplyButtonPushed, true);
            app.OutlierApplyButton.Text = 'Apply';
            app.PosthocDropDownLabel = uilabel(app.OutlierDetectionPanel);
            app.PosthocDropDownLabel.Text = 'Post-hoc:';
            app.OutlierPosthocDropDown = uidropdown(app.OutlierDetectionPanel);
            app.OutlierPosthocDropDown.Items = {'Ignore', 'MPV', 'NaN'};
            app.OutlierPosthocDropDown.ValueChangedFcn = createCallbackFcn(app, @rerunQCRSC, true);
            app.OutlierPosthocDropDown.Tooltip = {'For the corrected data. Either ignore outliers or replace with the QC mean feature value (MFV) or remove (replace with "not a number" NaN).'};
            app.OutlierPosthocDropDown.Value = 'NaN';
            app.StatisticsBeforeAfter95CIAfterPanel = uipanel(app.UIFigure);
            app.StatisticsBeforeAfter95CIAfterPanel.Title = 'Statistics: [Before] After (95% CI After)';
            app.RefRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.RefRSDBeforeAfterData.Text = '[NaN] NaN';
            app.refRSDLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.refRSDLabel.Tooltip = {''};
            app.refRSDLabel.Text = '%refRSD :';
            app.dRatioBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.dRatioBeforeAfterData.Text = '[NaN] NaN';
            app.dRatioLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.dRatioLabel.Tooltip = {''};
            app.dRatioLabel.Text = '%dRatio :';
            app.QCRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.QCRSDBeforeAfterData.Text = '[NaN] NaN';
            app.qcRSDLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.qcRSDLabel.Tooltip = {''};
            app.qcRSDLabel.Text = '%qcRSD :';
            app.BlankRatioData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.BlankRatioData.Text = '[NaN] NaN';
            app.blankRatioLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.blankRatioLabel.Tooltip = {''};
            app.blankRatioLabel.Text = '%blankRatio :';
            app.SampleRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.SampleRSDBeforeAfterData.Text = '[NaN] NaN';
            app.sampleRSDLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.sampleRSDLabel.Tooltip = {''};
            app.sampleRSDLabel.Text = '%sampleRSD :';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'res', 'QCconfigExplorer.png');
            app.BlankRatioPanel = uipanel(app.UIFigure);
            app.BlankRatioPanel.Title = 'Blank Ratio';
            app.MethodDropDownLabel_2 = uilabel(app.BlankRatioPanel);
            app.MethodDropDownLabel_2.Text = 'Method:';
            app.BlankRatioMethodDropDown = uidropdown(app.BlankRatioPanel);
            app.BlankRatioMethodDropDown.Items = {'QC', 'Median', 'Percentile'};
            app.BlankRatioMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @BlankRatioMethodDropDownValueChanged, true);
            app.BlankRatioMethodDropDown.Tooltip = {'BlankRatio = 100*max(BlankValue)/SampleReferenceValue. ''QC'' sets the SampleReferenceValue as the mean QC value; ''Median'' sets the SampleReferenceValue as the median Sample value; ''Percentile'' sets BlankRatio = % of Samples < max(BlankValue)*RelativeLOD (RelativeLOD is a user defined constant - default 1.5). In this instance missing values are considered = Blank value'};
            app.BlankRatioMethodDropDown.Value = 'QC';
            app.ThresholdLabel = uilabel(app.BlankRatioPanel);
            app.ThresholdLabel.Text = 'RelativeLOD:';
            app.RelativeLODEditField = uieditfield(app.BlankRatioPanel, 'numeric');
            app.RelativeLODEditField.ValueChangedFcn = createCallbackFcn(app, @RelativeLODEditFieldValueChanged, true);
            app.RelativeLODEditField.Tooltip = {'The RelativeLOD is a multiplier (relative to the Blank) used to set a value below which a feature is considered background noise. i.e., "estimated LOD = max(Blank) x relativeLOD"'};
            app.RelativeLODEditField.Value = 1.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QCRSCconfigExplorer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end